classdef DecorrApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        ResetAppMenu                    matlab.ui.container.Menu
        ImportImagingSettingsMenu       matlab.ui.container.Menu
        ImportControlSettingsMenu       matlab.ui.container.Menu
        ExportImagingSettingsMenu       matlab.ui.container.Menu
        ExportControlSettingsMenu       matlab.ui.container.Menu
        TestSerialConnectionMenu        matlab.ui.container.Menu
        ControlMenu                     matlab.ui.container.Menu
        ResetControlParametersMenu      matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        ReviewMenu                      matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        azel_init_axes                  matlab.ui.control.UIAxes
        rael_init_axes                  matlab.ui.control.UIAxes
        raaz_init_axes                  matlab.ui.control.UIAxes
        surf_init_axes                  matlab.ui.control.UIAxes
        SerialConnectionsPanel          matlab.ui.container.Panel
        RFControllerLabel               matlab.ui.control.Label
        RFControllerDropDown            matlab.ui.control.DropDown
        RFOutputDropDownLabel           matlab.ui.control.Label
        RFOutputDropDown                matlab.ui.control.DropDown
        VerifyButton                    matlab.ui.control.Button
        VerifyButton_2                  matlab.ui.control.Button
        EnableSwitchLabel               matlab.ui.control.Label
        EnableSwitch                    matlab.ui.control.Switch
        EnableSwitch_2Label             matlab.ui.control.Label
        EnableSwitch_2                  matlab.ui.control.Switch
        ReadyLamp_3Label                matlab.ui.control.Label
        rfcontrollight                  matlab.ui.control.Lamp
        ReadyLamp_4Label                matlab.ui.control.Label
        rfoutlight                      matlab.ui.control.Lamp
        resetButton                     matlab.ui.control.Button
        resetButton_2                   matlab.ui.control.Button
        GeneralControlsPanel            matlab.ui.container.Panel
        ModeButtonGroup                 matlab.ui.container.ButtonGroup
        ControlModeButton               matlab.ui.control.RadioButton
        PrerecordedDataModeButton       matlab.ui.control.RadioButton
        ImagingModeButton               matlab.ui.control.RadioButton
        ImagingSettingsPanel            matlab.ui.container.Panel
        AzimuthAngleEditFieldLabel      matlab.ui.control.Label
        AzimuthAngleEditField           matlab.ui.control.NumericEditField
        ElevationAngleEditFieldLabel    matlab.ui.control.Label
        ElevationAngleEditField         matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel         matlab.ui.control.Label
        FrameRateEditField              matlab.ui.control.NumericEditField
        VoxelstepEditFieldLabel         matlab.ui.control.Label
        VoxelstepEditField              matlab.ui.control.NumericEditField
        sigmaEditField_2Label           matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.NumericEditField
        FetchInfoButton                 matlab.ui.control.Button
        TargetDirectoryPanel            matlab.ui.container.Panel
        SetDirectoryButton              matlab.ui.control.Button
        DirectoryLabel                  matlab.ui.control.Label
        ROISettingsPanel                matlab.ui.container.Panel
        TabGroup2                       matlab.ui.container.TabGroup
        SingleEllipsoidTab              matlab.ui.container.Tab
        ElevationEditFieldLabel         matlab.ui.control.Label
        ElevationEditField              matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel           matlab.ui.control.Label
        AzimuthEditField                matlab.ui.control.NumericEditField
        RangeEditFieldLabel             matlab.ui.control.Label
        RangeEditField                  matlab.ui.control.NumericEditField
        Rz_outEditFieldLabel            matlab.ui.control.Label
        Rz_outEditField                 matlab.ui.control.NumericEditField
        Ry_outEditFieldLabel            matlab.ui.control.Label
        Ry_outEditField                 matlab.ui.control.NumericEditField
        Rx_outEditFieldLabel            matlab.ui.control.Label
        Rx_outEditField                 matlab.ui.control.NumericEditField
        ROICenterLabel                  matlab.ui.control.Label
        OuterRadiiLabel                 matlab.ui.control.Label
        BetaEditFieldLabel              matlab.ui.control.Label
        BetaEditField                   matlab.ui.control.NumericEditField
        GammaEditFieldLabel             matlab.ui.control.Label
        GammaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        AlphaEditField                  matlab.ui.control.NumericEditField
        AngleLabel                      matlab.ui.control.Label
        ROIModeDropDownLabel            matlab.ui.control.Label
        ROIModeDropDown                 matlab.ui.control.DropDown
        RangeMaxLabel                   matlab.ui.control.Label
        rangeMaxEditField_cutoff        matlab.ui.control.NumericEditField
        RangeMinLabel                   matlab.ui.control.Label
        rangeMinEditField_cutoff        matlab.ui.control.NumericEditField
        IBSnormboundsLabel              matlab.ui.control.Label
        SetIBSgeoButton                 matlab.ui.control.Button
        RangeMaxLabel_2                 matlab.ui.control.Label
        elMaxEditField_cutoff           matlab.ui.control.NumericEditField
        RangeMinLabel_2                 matlab.ui.control.Label
        elMinEditField_cutoff           matlab.ui.control.NumericEditField
        RangeMaxLabel_3                 matlab.ui.control.Label
        azMaxEditField_cutoff           matlab.ui.control.NumericEditField
        RangeMinLabel_3                 matlab.ui.control.Label
        azMinEditField_cutoff           matlab.ui.control.NumericEditField
        MultiEllipsoidTab               matlab.ui.container.Tab
        Rx_inEditFieldLabel             matlab.ui.control.Label
        Rx_inEditField                  matlab.ui.control.NumericEditField
        Ry_inEditFieldLabel             matlab.ui.control.Label
        Ry_inEditField                  matlab.ui.control.NumericEditField
        Rz_inEditFieldLabel             matlab.ui.control.Label
        Rz_inEditField                  matlab.ui.control.NumericEditField
        InnerRadiiLabel                 matlab.ui.control.Label
        ShrinkVolumetoROIButton         matlab.ui.control.Button
        RangeSliderLabel                matlab.ui.control.Label
        RangeSlider                     matlab.ui.control.Slider
        ElevationLabel_2                matlab.ui.control.Label
        ElevationSlider                 matlab.ui.control.Slider
        AzimuthLabel_2                  matlab.ui.control.Label
        AzimuthSlider                   matlab.ui.control.Slider
        AcquireButton_2                 matlab.ui.control.Button
        AcquireInitVolumeLabel          matlab.ui.control.Label
        EtcPanel                        matlab.ui.container.Panel
        UpdateAllButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        ExportSettingsButton            matlab.ui.control.Button
        ImportSettingsButton            matlab.ui.control.Button
        DynamicRangeSliderLabel         matlab.ui.control.Label
        DynamicRangeSlider              matlab.ui.control.Slider
        DecorrNormalizationButtonGroup  matlab.ui.container.ButtonGroup
        BothButton_2                    matlab.ui.control.RadioButton
        LocalButton                     matlab.ui.control.RadioButton
        GlobalButton                    matlab.ui.control.RadioButton
        TrialEditFieldLabel             matlab.ui.control.Label
        TrialEditField                  matlab.ui.control.NumericEditField
        ImagingTab                      matlab.ui.container.Tab
        decorr_imaging_axes             matlab.ui.control.UIAxes
        azel_imaging_axes               matlab.ui.control.UIAxes
        rael_imaging_axes               matlab.ui.control.UIAxes
        raaz_imaging_axes               matlab.ui.control.UIAxes
        rfout_axes                      matlab.ui.control.UIAxes
        ImagingcontrolsPanel            matlab.ui.container.Panel
        TrialtypeButtonGroup            matlab.ui.container.ButtonGroup
        ManualImagingModeButton         matlab.ui.control.RadioButton
        AutomaticImagingModeButton      matlab.ui.control.RadioButton
        ManualControlModeButton         matlab.ui.control.RadioButton
        AutomaticControlModeButton      matlab.ui.control.RadioButton
        ControlThreshEditFieldLabel     matlab.ui.control.Label
        ControlThreshEditField          matlab.ui.control.NumericEditField
        MotioncorrectCheckBox           matlab.ui.control.CheckBox
        NumshamEditFieldLabel           matlab.ui.control.Label
        NumshamEditField                matlab.ui.control.NumericEditField
        RangeSlider_2Label              matlab.ui.control.Label
        RangeSlider_imaging             matlab.ui.control.Slider
        ElevationLabel_3                matlab.ui.control.Label
        ElevationSlider_imaging         matlab.ui.control.Slider
        AzimuthLabel_3                  matlab.ui.control.Label
        AzimuthSlider_imaging           matlab.ui.control.Slider
        BmodeDynamicRangeLabel          matlab.ui.control.Label
        DynamicRangeSlider_imaging      matlab.ui.control.Slider
        ManualPanel                     matlab.ui.container.Panel
        RecordVolumeButton              matlab.ui.control.Button
        ReadyLampLabel                  matlab.ui.control.Label
        ReadyLamp                       matlab.ui.control.Lamp
        CurrentVolumeEditFieldLabel     matlab.ui.control.Label
        CurrentVolumeEditField          matlab.ui.control.EditField
        CompletionTimeEditFieldLabel    matlab.ui.control.Label
        CompletionTimeManualEditField   matlab.ui.control.EditField
        AutomaticPanel                  matlab.ui.container.Panel
        ReadyLamp_2Label                matlab.ui.control.Label
        ReadyLamp_2                     matlab.ui.control.Lamp
        StartRecordingButton            matlab.ui.control.Button
        PauseButton                     matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
        ActualvolumesEditFieldLabel     matlab.ui.control.Label
        ActualvolumesEditField          matlab.ui.control.EditField
        CompletionTimeEditFieldLabel_2  matlab.ui.control.Label
        CompletionTimeAutoEditField     matlab.ui.control.EditField
        EndTrialButton                  matlab.ui.control.Button
        ShamvolumesEditFieldLabel       matlab.ui.control.Label
        ShamvolumesEditField            matlab.ui.control.NumericEditField
        SaveRFButton                    matlab.ui.control.Button
        CurrentDecorrelationEditFieldLabel  matlab.ui.control.Label
        CurrentDecorrelationEditField   matlab.ui.control.NumericEditField
        ElapsedTimeEditFieldLabel       matlab.ui.control.Label
        ElapsedTimeEditField            matlab.ui.control.NumericEditField
        logArtifDecorrEditFieldLabel    matlab.ui.control.Label
        logArtifDecorrEditField         matlab.ui.control.NumericEditField
        DynamicRangeSlider_2Label_2     matlab.ui.control.Label
        DecorrDynamicRangeSlider_imaging  matlab.ui.control.Slider
        MindecdisplayEditFieldLabel     matlab.ui.control.Label
        MindecdisplayEditField          matlab.ui.control.NumericEditField
        OverridePanel                   matlab.ui.container.Panel
        ActivateRFgenButton             matlab.ui.control.Button
        StopimagingButton               matlab.ui.control.Button
        ReviewTab                       matlab.ui.container.Tab
        azel_review_axes                matlab.ui.control.UIAxes
        raaz_review_axes                matlab.ui.control.UIAxes
        rael_review_axes                matlab.ui.control.UIAxes
        review_decorr_axes              matlab.ui.control.UIAxes
        ReviewControlPanel              matlab.ui.container.Panel
        LoadExperimentButton            matlab.ui.control.Button
        Label                           matlab.ui.control.Label
        DirectoryLabel_2                matlab.ui.control.Label
        LoadExperimentfromfileButton    matlab.ui.control.Button
        ExportButton                    matlab.ui.control.Button
        SigmaEditFieldLabel             matlab.ui.control.Label
        SigmaEditField                  matlab.ui.control.NumericEditField
        ThreshEditFieldLabel            matlab.ui.control.Label
        ThreshEditField                 matlab.ui.control.NumericEditField
        SigmaEditField_2Label           matlab.ui.control.Label
        SigmaEditField_2                matlab.ui.control.EditField
        NumberofVolumesEditFieldLabel   matlab.ui.control.Label
        NumberofVolumesEditField        matlab.ui.control.EditField
        DecorrThreshEditField_2Label    matlab.ui.control.Label
        DecorrThreshEditField_2         matlab.ui.control.EditField
        FrameRateEditField_2Label       matlab.ui.control.Label
        FrameRateEditField_2            matlab.ui.control.NumericEditField
        Panel2                          matlab.ui.container.Panel
        beeginbutton                    matlab.ui.control.Button
        endbutton                       matlab.ui.control.Button
        back1button                     matlab.ui.control.Button
        forward1button                  matlab.ui.control.Button
        SelectDataDropDownLabel         matlab.ui.control.Label
        SelectDataDropDown              matlab.ui.control.DropDown
        Panel2_2                        matlab.ui.container.Panel
        RangeSlider_2Label_2            matlab.ui.control.Label
        RangeSlider_review              matlab.ui.control.Slider
        ElevationLabel                  matlab.ui.control.Label
        ElevationSlider_review          matlab.ui.control.Slider
        AzimuthLabel                    matlab.ui.control.Label
        AzimuthSlider_review            matlab.ui.control.Slider
    end

    
    properties (Access = public)
        experiment; % Main experiment class
        isoDisplay;
        serialDevList; 
        currDisplayVol;
        recordingActive;
        paused; 
        imagingMode; 
        ROISettingsList = {'ROISettingsPanel','ROIModeDropDownLabel','ROIModeDropDown','ROICenterLabel', ...
                            'OuterRadiiLabel','InnerRadiiLabel','x0EditFieldLabel','x0EditField','y0EditFieldLabel',...
                            'y0EditField','z0EditFieldLabel','z0EditField','Rx_outEditFieldLabel','Rx_outEditField',...
                            'Ry_outEditFieldLabel','Ry_outEditField','Rz_outEditFieldLabel','Rz_outEditField',...
                            'Rx_inEditFieldLabel','Rx_inEditField','Ry_inEditFieldLabel','Ry_inEditField',...
                            'Rz_inEditFieldLabel','Rz_inEditField'};
        serialOutObj;
        serialInObj; 
        rfDatOut; 
        serialInBaudRate; 
        serialInTime;
    end
    
    methods (Access = public)
        function changeUIExperimentState(app)
            experimentState = app.experiment.machineState; 
            switch experimentState
                case 0
                    activeComponents = {};
                    inactiveComponents = {};
                case 1
                    activeComponents = {};
                    inactiveComponents = {};
                case 2
                    activeComponents = {};
                    inactiveComponents = {};
                case 3
                    activeComponents = {};
                    inactiveComponents = {};
                case 4
                    activeComponents = {};
                    inactiveComponents = {};
                case 5
                    activeComponents = {};
                    inactiveComponents = {};
                case 6
                    activeComponents = {};
                    inactiveComponents = {};
                otherwise
                    
            end
            for currUIElem = 1:numel(activeComponents)
                app.(activeComponents{currUIElem}).Enable = true;
            end
            
            for currUIElem = 1:numel(inactiveComponents)
                app.(activeComponents{currUIElem}).Enable = false;
            end
        end
        function updateSerialDevices(app)
            app.serialDevList = serialportlist;
            app.RFControllerDropDown.Items = app.serialDevList; 
            app.RFOutputDropDown.Items = app.serialDevList; 
        end
        function updateImagingParams(app)
            % Get params from GUI
            thisphimax = deg2rad(app.ElevationAngleEditField.Value/2);
            thisphimin = -thisphimax;
            thisthetamax = deg2rad(app.AzimuthAngleEditField.Value/2);
            thisthetamin = -thisphimax;
            thiscartScalingFactor = app.VoxelstepEditField.Value; 
            thisFrameRate = app.FrameRateEditField.Value; 
            thissigma = app.sigmaEditField.Value; 
            % Init in the main experiment object
            app.experiment.setImagingParams(thisthetamin,thisthetamax,thisphimin,thisphimax,thiscartScalingFactor,thisFrameRate,thissigma)
        end
        function updateROIParams(app)
            y0 = app.AzimuthEditField.Value;
            x0 = app.ElevationEditField.Value; 
            z0 = app.RangeEditField.Value; 
            Rx_in = app.Rx_inEditField.Value;
            Ry_in = app.Ry_inEditField.Value;
            Rz_in = app.Rz_inEditField.Value;
            Rx_out = app.Rx_outEditField.Value;
            Ry_out = app.Ry_outEditField.Value;
            Rz_out = app.Rz_outEditField.Value;
            alphaAng = app.AlphaEditField.Value; 
            betaAng = app.BetaEditField.Value; 
            gammaAng = app.GammaEditField.Value; 
            ROIMode = app.ROIModeDropDown.Value;
            app.experiment.setROIParams(x0,y0,z0,Rx_out,Ry_out,Rz_out,Rx_in,Ry_in,Rz_in,alphaAng,gammaAng,betaAng);
            updateInitAxes(app);
            testROI = app.experiment.ROIMap; 
            cla(app.surf_init_axes);
            app.isoDisplay = isosurface(testROI); 
            hold(app.surf_init_axes,'on')
            patch(app.surf_init_axes ,app.isoDisplay,'FaceColor','red');
            [xSize,ySize,zSize,~] = size(app.experiment.initDataSet.rawData_cart);
            axis(app.surf_init_axes, [0 xSize 0 ySize 0 zSize])
            [x_pz, y_pz] = meshgrid(0:1:xSize,0:1:ySize);
            z_pz = (zSize-floor(app.RangeSlider.Value))*ones(size(x_pz)); %-zMid;
            [x_py, z_py] = meshgrid(0:1:xSize,0:1:zSize);
            y_py = (ySize-floor(app.ElevationSlider.Value))*ones(size(x_py)); %-yMid;
            [y_px, z_px] = meshgrid(0:1:ySize,0:1:zSize);
            x_px = (xSize-floor(app.AzimuthSlider.Value))*ones(size(y_px)); %-xMid;
            pz_surf = surf(app.surf_init_axes,y_pz,z_pz,x_pz);
            px_surf = surf(app.surf_init_axes,y_py,z_py,x_py);
            py_surf = surf(app.surf_init_axes,y_px,z_px,x_px);
            hold(app.surf_init_axes,'off')
        end
        function updateIBSParam(app)
            IBSrMax=app.rangeMaxEditField_cutoff.Value;
            IBSrMin=app.rangeMinEditField_cutoff.Value;
            IBSElMax=app.elMaxEditField_cutoff.Value;
            IBSElMin=app.elMinEditField_cutoff.Value;
            IBSAzMax=app.azMaxEditField_cutoff.Value;
            IBSAzMin=app.azMinEditField_cutoff.Value;
            app.experiment.setIBSparam(IBSrMin,IBSrMax,IBSAzMin,IBSAzMax,IBSElMin,IBSElMax)
        end
        function initInitAxes(app)
            [zMax,yMax,xMax,~] = size(app.experiment.initDataSet.rawData_cart);
            app.ElevationSlider.Limits = [1, xMax];
            app.AzimuthSlider.Limits = [1,yMax];
            app.RangeSlider.Limits = [1,zMax];
        end
        function initImagingAxes(app)
            [zMax,yMax,xMax,~] = size(app.experiment.initDataSet(1).rawData_cart);
            %app.experiment.initDataSet(1).rawData_cart.x_range;
            app.ElevationSlider_imaging.Limits = [1, xMax];
            app.AzimuthSlider_imaging.Limits = [1,yMax];
            app.RangeSlider_imaging.Limits = [1,zMax];
        end
        function updateInitAxes(app)
            %%% vars %%%
            %% Get Slider Values
            bMode_scale = 20*log10(.25); 
            elev = floor(app.ElevationSlider.Value);
            azim = floor(app.AzimuthSlider.Value); 
            ra = floor(app.RangeSlider.Value); 
            dynamicRange = app.DynamicRangeSlider.Value; 
            %% get volume for display
            displayVol = 20*log10(abs(squeeze(app.experiment.initDataSet.rawData_cart(:,:,:,1)))); 
            [zSize,ySize,xSize] = size(displayVol);
            %% get ROI 
            
            [az_el_overlay,ra_az_overlay,ra_el_overlay]=app.genROIOverlay(ra,azim,elev,true);
            
            %%% Plots %%%
            %% az-el %%
            cla(app.azel_init_axes);
            hold(app.azel_init_axes,'on');
                BmodeIn = squeeze(displayVol(ra,:,:));
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                imagesc(app.azel_init_axes,app.experiment.yVec,app.experiment.xVec,BmodeScaled);
                colormap(app.azel_init_axes,gray);
                green = zeros(xSize,ySize,3);
                green(:,:,2) = 1; 
                h = imshow(green, 'XData',app.experiment.yVec,'YData',app.experiment.xVec,'Parent',app.azel_init_axes);
                axis(app.azel_init_axes,'on')
                set(h,'AlphaData',az_el_overlay);
                xlim(app.azel_init_axes,[app.experiment.xVec(1),app.experiment.xVec(end)]);
                ylim(app.azel_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
            hold(app.azel_init_axes,'off')
            
            %% Ra - Az
            cla(app.raaz_init_axes);
            hold(app.raaz_init_axes,'on');
                BmodeIn = squeeze(displayVol(:,:,elev))';
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                
                imagesc(app.raaz_init_axes,app.experiment.xVec,app.experiment.zVec,BmodeScaled');
                colormap(app.raaz_init_axes,gray);
                green = zeros(zSize,xSize,3);
                green(:,:,2) = 1; 
                k = imshow(green, 'XData',app.experiment.xVec,'YData',app.experiment.zVec,'Parent',app.raaz_init_axes);
                axis(app.raaz_init_axes,'on')
                
                set(k,'AlphaData',ra_az_overlay);
                
                xlim(app.raaz_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
                ylim(app.raaz_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
                set(app.raaz_init_axes,'YDir','reverse') 
                
            hold(app.raaz_init_axes,'off')
            
            %% Ra - El 
            cla(app.rael_init_axes);
            hold(app.rael_init_axes,'on');
                BmodeIn = squeeze(displayVol(:,azim,:))';
                BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
                BmodeScaled(BmodeScaled<0) = 0;
                BmodeScaled(BmodeScaled>1) = 1;
                imagesc(app.rael_init_axes,app.experiment.yVec,app.experiment.zVec,(BmodeScaled'))
                colormap(app.rael_init_axes,gray);
                green = zeros(zSize,ySize,3);
                green(:,:,2) = 1; 
                l = imshow(green, 'XData',app.experiment.yVec,'YData',app.experiment.zVec,'Parent',app.rael_init_axes);
                axis(app.rael_init_axes,'on')
                
                set(l,'AlphaData',ra_el_overlay);
                
                xlim(app.rael_init_axes,[app.experiment.yVec(1),app.experiment.yVec(end)]);
                ylim(app.rael_init_axes,[app.experiment.zVec(1),app.experiment.zVec(end)]);
            hold(app.rael_init_axes,'off')
        end
        function updateDirectory(app)
            app.DirectoryLabel.Text = app.experiment.dataFolder; 
        end
        function exportExperiment(app)
            exper2export = app.experiment;
            filename = inputdlg('Enter File name');
            save(strcat(filename{1},'.mat'), 'exper2export')
        end
        function hybrid_RGB = generateDecorrOverlay(app, BmodeIn, decorrIn,bMode_scale, overlayIn)
            %% Scale Decorr
            minDecorrVal = app.MindecdisplayEditField.Value;
            belowMinDecorr = decorrIn < minDecorrVal;
            decDynRange = app.DecorrDynamicRangeSlider_imaging.Value;
            decDynRangeBMap= @(x) (20 /decDynRange) .* (x - minDecorrVal);
            decorrScaled = decDynRangeBMap(decorrIn); 
            decorrScaled(belowMinDecorr)=0;
            %% Scale Bmode
            dynamicRange = app.DynamicRangeSlider_imaging.Value; 
            BmodeScaled = (BmodeIn-bMode_scale+dynamicRange)/(dynamicRange);
            BmodeScaled(BmodeScaled<0) = 0;
            BmodeScaled(BmodeScaled>1) = 1;
            %% construct images
            % construct RGB image from B-mode background
            delta_RGB = ind2rgb(uint8(decorrScaled*255),hot(256));
            bmode_RGB(:,:,1) = BmodeScaled;
            bmode_RGB(:,:,2) = bmode_RGB(:,:,1);
            bmode_RGB(:,:,3) = bmode_RGB(:,:,1);
            % define transparency as proportional amount of overlay
            transparency(:,:,1) = decorrScaled;
            transparency(:,:,2) = decorrScaled(:,:,1);
            transparency(:,:,3) = decorrScaled(:,:,1);
            hybrid_RGB = bmode_RGB.*(1-transparency) + delta_RGB.*transparency;
            hybrid_RGB(belowMinDecorr)=bmode_RGB(belowMinDecorr);
            hybrid_RGB(hybrid_RGB>1) = 1;
            hybrid_RGB(hybrid_RGB<0) = 0;
            %% Overlay region as green pixels
            green = hybrid_RGB(:,:,2);
            green(overlayIn) = 1; 
            hybrid_RGB(:,:,2) = green; 
        end
        function [az_el,ra_az,ra_el]=genROIOverlay(app,ra,azim,elev,showIBSGeo)
            % return binary slices at ra/az/el where points around the ROI
            % are ones 
            if(isempty(app.experiment.ROIMap))
                [raSize,azSize,elSize,~]=size(app.experiment.initDataSet.rawData_cart);
                az_el=zeros(azSize,elSize);
                ra_az=zeros(raSize,azSize);
                ra_el=zeros(raSize,elSize);
                return
            end
            
            az_el=edge(squeeze(app.experiment.ROIMap(ra,:,:)));
            ra_az=edge(squeeze(app.experiment.ROIMap(:,:,elev)));
            ra_el=edge(squeeze(app.experiment.ROIMap(:,azim,:)));
            if showIBSGeo
                %% get bounds
                validRa=find(app.experiment.zVec>app.rangeMinEditField_cutoff.Value & app.experiment.zVec<app.rangeMaxEditField_cutoff.Value);
                    minRaInd=validRa(1);maxRaInd=validRa(end);
                validAz=find(app.experiment.yVec>app.azMinEditField_cutoff.Value & app.experiment.yVec<app.azMaxEditField_cutoff.Value);
                    minAzInd=validAz(1);maxAzInd=validAz(end);
                validEl=find(app.experiment.xVec>app.elMinEditField_cutoff.Value & app.experiment.xVec<app.elMaxEditField_cutoff.Value);
                    minElInd=validEl(1);maxElInd=validEl(end);
                %% add to mask
                az_el(:,minElInd)=1;az_el(:,maxElInd)=1;
                az_el(minAzInd,:)=1;az_el(maxAzInd,:)=1;
                ra_az(:,minAzInd)=1;ra_az(:,maxAzInd)=1;
                ra_az(minRaInd,:)=1;ra_az(maxRaInd,:)=1;
                ra_el(:,minElInd)=1;ra_el(:,maxElInd)=1;
                ra_el(minRaInd,:)=1;ra_el(maxRaInd,:)=1;
            end
        end
        function updateImagingAxis(app)
            initImagingAxes(app)
            %%% vars %%%
            bMode_scale = 20*log10(.25); 
            %% Get Slider Values
            elev = floor(app.ElevationSlider_imaging.Value);
            azim = floor(app.AzimuthSlider_imaging.Value); 
            ra = floor(app.RangeSlider_imaging.Value); 
            %% get volume for display
            if ~isempty(app.experiment.ultrasoundDataSeries) 
                displayVol = 20*log10(abs(squeeze(app.experiment.ultrasoundDataSeries(end).rawData_cart(:,:,:,1)))); 
                decorrVol = app.experiment.cumulativeDecorr;
            elseif ~isempty(app.experiment.ultrasoundShamDataSeries)
                displayVol = 20*log10(abs(squeeze(app.experiment.ultrasoundShamDataSeries(end).rawData_cart(:,:,:,1)))); 
                decorrVol = app.experiment.cumulativeShamDecorr;
            else
                return
            end
            %% get ROI 
            [az_el_overlay,ra_az_overlay,ra_el_overlay]=app.genROIOverlay(ra,azim,elev,false);
            %% % Plots %%%
            decorrVol(decorrVol<=0)=realmin;
            decorrVol=log10(decorrVol);
            %% az-el %%
            BmodeIn = squeeze(displayVol(ra,:,:));
            decorrIn = squeeze(decorrVol(ra,:,:));
            hybrid_RGB_azel=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, az_el_overlay);
            image(app.azel_imaging_axes,app.experiment.yVec,app.experiment.xVec,hybrid_RGB_azel);
            
            %% Ra - Az
            cla(app.raaz_imaging_axes);
            BmodeIn = squeeze(displayVol(:,:,elev));
            decorrIn = squeeze(decorrVol(:,:,elev));
            hybrid_RGB_raaz=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, ra_az_overlay);
            image(app.raaz_imaging_axes,app.experiment.yVec,app.experiment.zVec,hybrid_RGB_raaz);
            %% Ra - El 
            cla(app.rael_imaging_axes);
            BmodeIn = squeeze(displayVol(:,azim,:));
            decorrIn = squeeze(decorrVol(:,azim,:));
            hybrid_RGB_rael=app.generateDecorrOverlay(BmodeIn, decorrIn,bMode_scale, ra_el_overlay);
            image(app.rael_imaging_axes,app.experiment.yVec,app.experiment.zVec,hybrid_RGB_rael);
            %% scale images
            axis(app.rael_imaging_axes,'image')
            axis(app.raaz_imaging_axes,'image')
            axis(app.azel_imaging_axes,'image')
        end
        function update3DinitAxes(app)
            [x_e, y_e, z_e] = ellipsoid(app.experiment.ROIy0,app.experiment.ROIz0,app.experiment.ROIx0,app.experiment.ROIr1,app.experiment.ROIr2,app.experiment.ROIr0,30);
            surf(app.surf_init_axes,x_e,y_e,z_e);
        end
        function plotDecorr(app)
            usTimeArr = app.experiment.getTimeArr();
            usTimeArr = [usTimeArr{:}];
            try
                elapsedTime = minutes(usTimeArr(end) - usTimeArr(2));
            app.ElapsedTimeEditField.Value = elapsedTime;
            catch
            end
            try
            rfTimeDat = arrayfun(@(x) x.time, app.rfDatOut,'UniformOutput',false);
            rfTimeDat =  [rfTimeDat{:}];
            rfImpDat = arrayfun(@(x) x.impedance, app.rfDatOut,'UniformOutput',false);
            rfImpDat =  [rfImpDat{:}];
            rfTempDat = arrayfun(@(x) x.t1, app.rfDatOut,'UniformOutput',false);
            rfTempDat =  [rfTempDat{:}];
            
            catch
            end
            plot(app.decorr_imaging_axes,usTimeArr,log10(app.experiment.decorrAverageSeriesROI), ...
                usTimeArr,log10(app.experiment.decorrAverageSeries)); 
            try
            plot(app.rfout_axes, ...
                rfTimeDat, rfTempDat);
            catch
            end
            legend(app.decorr_imaging_axes,'Average in ROI','Average in volume','Location','southeast')
            xlabel(app.decorr_imaging_axes,'Volume')
            ylabel(app.decorr_imaging_axes,'Decorrelation')
            ylim(app.decorr_imaging_axes,[-6 0])
            try
            app.CurrentDecorrelationEditField.Value = log10(app.experiment.decorrAverageSeriesROI(end));
            catch
            end
        end
        function succ = nextDataSet(app)
            if ~app.MotioncorrectCheckBox.Value
                output = app.experiment.addNextRawDataSet_c();
            else
                output=app.experiment.addNextRawDataSetCorrected_c();
            end
            plotDecorr(app)
            if output == -1
                succ = false; 
            else
                succ = true; 
            end
        end
        function loadExperimentFromFile(app)
            myFile = uigetfile();
            inp = load(myFile);
            app.experiment  = inp.exper2export; 
        end
        function initSerialIn(app,serialString,baudRate)
            app.serialInObj = serialport(serialString,baudRate);
            app.rfDatOut = [];
            app.serialInBaudRate = baudRate/2/8;
        end
        function getSerialBlock(app)
            dat = app.serialInObj.read(app.serialInObj.NumBytesAvailable,'char');
            app.serialInTime = datetime();
            outDat = obj.parseSerialBlock(dat, obj.startTimeData,obj.byteRate);
        end
    end
    
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fileName = mfilename('fullpath');
            addpath(genpath(fileName(1:end-length(mfilename('class')))));
            updateSerialDevices(app);
            app.recordingActive = false;
            app.paused = false;
            app.imagingMode = 1; 
        end

        % Button pushed function: SetDirectoryButton
        function SetDirectoryButtonPushed(app, event)
            app.experiment = ExperimentClass(); 
            app.experiment.initDataFolderGUI();
            updateDirectory(app);
        end

        % Button pushed function: UpdateAllButton
        function UpdateAllButtonPushed(app, event)
            
        end

        % Value changed function: FrameRateEditField
        function FrameRateEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: AzimuthAngleEditField
        function AzimuthAngleEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: ElevationAngleEditField
        function ElevationAngleEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Value changed function: VoxelstepEditField
        function VoxelstepEditFieldValueChanged(app, event)
            updateImagingParams(app)
        end

        % Button pushed function: AcquireButton_2
        function AcquireButton_2Pushed(app, event)
            app.AcquireButton_2.Text = "Processing";
            updateImagingParams(app)
            app.experiment.getInitDataSet_c(); 
            initInitAxes(app);
            app.AcquireButton_2.Text = "Request";
        end

        % Value changed function: ElevationSlider
        function ElevationSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: RangeSlider
        function RangeSliderValueChanged(app, event)
           updateInitAxes(app)
        end

        % Value changed function: AzimuthSlider
        function AzimuthSliderValueChanged(app, event)
            updateInitAxes(app)
        end

        % Value changed function: ROIModeDropDown
        function ROIModeDropDownValueChanged(app, event)
            updateROIParams(app);
            update3DinitAxes(app);
        end

        % Value changed function: Rx_outEditField
        function Rx_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: ElevationEditField
        function ElevationEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: AzimuthEditField
        function AzimuthEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: RangeEditField
        function RangeEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Ry_outEditField
        function Ry_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Rz_outEditField
        function Rz_outEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Rx_inEditField
        function Rx_inEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: Ry_inEditField
        function Ry_inEditFieldValueChanged(app, event)
             updateROIParams(app);
        end

        % Value changed function: Rz_inEditField
        function Rz_inEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: BetaEditField
        function BetaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Value changed function: GammaEditField
        function GammaEditFieldValueChanged(app, event)
            updateROIParams(app);
        end

        % Button pushed function: RecordVolumeButton
        function RecordVolumeButtonPushed(app, event)
            app.RecordVolumeButton.Text = "Processing";
            app.ReadyLamp.Color = [1 0 0];
            drawnow; 
            tic;
            succ = nextDataSet(app);
            timeToComplete = toc;
            if succ
                app.CompletionTimeManualEditField.Value = num2str(timeToComplete);
            end
            updateImagingAxis(app);
            app.ReadyLamp.Color = [0 1 0];
            app.RecordVolumeButton.Text = "Record Volume";
        end

        % Value changed function: ControlThreshEditField
        function ControlThreshEditFieldValueChanged(app, event)
            myThresh = app.ControlThreshEditField.Value;
            app.experiment.setControlParams(myThresh);
        end

        % Callback function
        function sigmaEditFieldValueChanged(app, event)
            app.updateImagingParams()
        end

        % Value changed function: ElevationSlider_imaging
        function ElevationSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: AzimuthSlider_imaging
        function AzimuthSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changing function: AzimuthSlider_imaging
        function AzimuthSlider_imagingValueChanging(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: RangeSlider_imaging
        function RangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Callback function
        function PlaceROIraazButtonPushed(app, event)
            setROIGraphically(app,app.raaz_init_axes,'raaz')
        end

        % Button pushed function: ShrinkVolumetoROIButton
        function ShrinkVolumetoROIButtonPushed(app, event)
            app.experiment.defineGridBounds();
            updateInitAxes(app);
        end

        % Button pushed function: LoadExperimentButton
        function LoadExperimentButtonPushed(app, event)
            initReviewPage(app)
            app.currDisplayVol = 1; 
        end

        % Value changed function: RangeSlider_review
        function RangeSlider_reviewValueChanged(app, event)
            updateReviewAxis(app);
        end

        % Value changed function: AzimuthSlider_review
        function AzimuthSlider_reviewValueChanged(app, event)

            updateReviewAxis(app);
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            exportExperiment(app);
        end

        % Button pushed function: LoadExperimentfromfileButton
        function LoadExperimentfromfileButtonPushed(app, event)
            loadExperimentFromFile(app); 
        end

        % Value changed function: SelectDataDropDown
        function SelectDataDropDownValueChanged(app, event)
            value = app.SelectDataDropDown.Value;
            app.currDisplayVol = str2num(value{1});
        end

        % Button pushed function: StartRecordingButton
        function StartRecordingButtonPushed(app, event)
            app.StartRecordingButton.Text = "Active";
            app.recordingActive=true;
            app.ReadyLamp_2.Color = [1 0 0];
            app.updateImagingAxis();
            app.recordingActive = true; 
            %% 
            while length(app.experiment.ultrasoundShamDataSeries) < app.NumshamEditField.Value && app.recordingActive 
                app.experiment.addNextShamDataSet_c;
                app.ShamvolumesEditField.Value=length(app.experiment.ultrasoundShamDataSeries);
                try
                app.logArtifDecorrEditField.Value=log10(sum(app.experiment.cumulativeShamDecorr(:).*app.experiment.ROIMap(:))/sum(app.experiment.ROIMap(:)));
                catch
                end
                app.updateImagingAxis()
                drawnow;
            end
            %% Start ablation
            try
                app.experiment.sendSerialData();
            catch
                disp('Could not send data');
            end
            while app.recordingActive 
                app.ActualvolumesEditField.Value = num2str(numel(app.experiment.ultrasoundDataSeries));
                drawnow;
                if app.paused 
                    try
                            app.experiment.sendSerialData();
                        catch
                            disp('Could not send data')
                    end
                    app.StartRecordingButton.Text = "Paused";
                    app.ReadyLamp_2.Color = [1 1 0];
                    drawnow; 
                end
                while app.paused
                    drawnow; 
                    if ~app.paused 
                        try
                            %app.experiment.sendSerialData();
                        catch
                            disp('Could not send data')
                        end
                        app.StartRecordingButton.Text = "Active";
                        app.ReadyLamp_2.Color = [1 0 0];
                        drawnow; 
                    end
                end
                tic;
                nextDataSet(app);
                try
                    [app.experiment.inSerialObj,sBlock] = app.experiment.inSerialObj.getSerialBlock();
                    if isa(sBlock,'struct')
                        app.rfDatOut = [app.rfDatOut,sBlock];
                    end
                catch
                end
                timeToComp = toc;
                app.CompletionTimeAutoEditField.Value = num2str(timeToComp);
                if ~isempty(app.experiment.ultrasoundDataSeries)
                updateImagingAxis(app);
                
                drawnow;
                
                if app.imagingMode == 4 && app.experiment.decorrExceedsThresh()
                    app.experiment.sendSerialData();
                    app.recordingActive = false; 
                end 
                end
            end
            updateImagingAxis(app);
            app.ReadyLamp_2.Color = [0 1 0];
            app.StartRecordingButton.Text = "Record Volume";
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            if app.paused 
                app.PauseButton.Text = "pause";
            else
                app.PauseButton.Text = "unpause";
            end
            app.paused = ~app.paused;
            drawnow; 
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.recordingActive
                app.recordingActive = false;
                try
                    app.experiment.sendSerialData();
                catch
                    disp('No serial device');
                end
            end
        end

        % Selection changed function: TrialtypeButtonGroup
        function TrialtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TrialtypeButtonGroup.SelectedObject;
            opts = {'Manual Imaging Mode','Automatic Imaging Mode','Manual Control Mode','Automatic Control Mode'};
            for i = 1:numel(opts)
                if strcmp(opts{i}, selectedButton.Text)
                    app.imagingMode = i; 
                end
            end
        end

        % Value changed function: RFControllerDropDown
        function RFControllerDropDownValueChanged(app, event)
            value = app.RFControllerDropDown.Value;
            app.experiment.setSerialOutName(value);
        end

        % Value changed function: EnableSwitch_2
        function EnableSwitch_2ValueChanged(app, event)
            value = app.EnableSwitch_2.Value;
            if strcat(value,'On')
                try
                    app.experiment.setUpSerialOutConnection();
                    app.rfcontrollight.Color = [0,1,0];
                catch 
                    app.rfcontrollight.Color = [1,0,0];
                end
            else
                app.experiment.removeSerialConnection();
            end
        end

        % Button pushed function: VerifyButton
        function VerifyButtonPushed(app, event)
            app.experiment.sendSerialData();
        end

        % Button pushed function: resetButton
        function resetButtonPushed(app, event)
            instrreset;
            app.rfcontrollight.Color = [1,1,0];
            app.EnableSwitch_2.Value = 'Off';
        end

        % Button pushed function: FetchInfoButton
        function FetchInfoButtonPushed(app, event)
            infoOut = app.experiment.getInitInfo;
            app.experiment.frameRate = infoOut(1);
            app.experiment.phiRange = infoOut(3);
            app.experiment.thetaRange = infoOut(4);
            app.FrameRateEditField.Value = app.experiment.frameRate;
            app.AzimuthAngleEditField.Value = app.experiment.phiRange;
            app.ElevationAngleEditField.Value = app.experiment.thetaRange;
        end

        % Value changed function: DynamicRangeSlider
        function DynamicRangeSliderValueChanged(app, event)
            app.updateInitAxes()
        end

        % Value changed function: DynamicRangeSlider_imaging
        function DynamicRangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: EnableSwitch
        function EnableSwitchValueChanged(app, event)
            value = app.EnableSwitch.Value;
            if strcat(value,'On')
                try
                    app.experiment.setUpSerialInConnection();
                    app.experiment.inSerialObj.initSerialBlocks();
                    app.rfoutlight.Color = [0,1,0];
                    app.rfDatOut = [];
                catch 
                    app.rfoutlight.Color = [1,0,0];
                end
            else
                app.experiment.removeSerialConnection();
            end
        end

        % Value changed function: RFOutputDropDown
        function RFOutputDropDownValueChanged(app, event)
            value = app.RFOutputDropDown.Value;
            app.experiment.setSerialInName(value);
        end

        % Button pushed function: EndTrialButton
        function EndTrialButtonPushed(app, event)
            try
                app.experiment.inSerialObj.cleanUp();
                
            catch
                
            end
            outDat = app.experiment.saveObj_withrf(app.rfDatOut);
                outFileName = strcat('FullExp_',date,'_',num2str(app.TrialEditField.Value),'.mat');
                save(outFileName,'outDat','-v7.3');
        end

        % Button pushed function: SetIBSgeoButton
        function SetIBSgeoButtonPushed(app, event)
            IBSrMax=app.rangeMaxEditField_cutoff.Value;
            IBSrMin=app.rangeMinEditField_cutoff.Value;
            IBSElMax=app.elMaxEditField_cutoff.Value;
            IBSElMin=app.elMinEditField_cutoff.Value;
            IBSAzMax=app.azMaxEditField_cutoff.Value;
            IBSAzMin=app.azMinEditField_cutoff.Value;
            app.experiment.setIBSparam(IBSrMin,IBSrMax,IBSAzMin,IBSAzMax,IBSElMin,IBSElMax)
        end

        % Callback function
        function AcquireButtonPushed(app, event)
            for i = 1:app.NumshamEditField.Value
                app.experiment.addNextShamDataSet_c;
            end
        end

        % Callback function
        function AcquireButton_3Pushed(app, event)
            
        end

        % Value changed function: DecorrDynamicRangeSlider_imaging
        function DecorrDynamicRangeSlider_imagingValueChanged(app, event)
            app.updateImagingAxis();
        end

        % Value changed function: rangeMaxEditField_cutoff
        function rangeMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Value changed function: rangeMinEditField_cutoff
        function rangeMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Value changed function: azMaxEditField_cutoff
        function azMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Value changed function: azMinEditField_cutoff
        function azMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Value changed function: elMaxEditField_cutoff
        function elMaxEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Value changed function: elMinEditField_cutoff
        function elMinEditField_cutoffValueChanged(app, event)
            app.updateInitAxes();
            app.updateIBSParam();
        end

        % Button pushed function: SaveRFButton
        function SaveRFButtonPushed(app, event)
            rfData = app.rfDatOut;
            outFileName = strcat('RFData_',date,'_',num2str(app.TrialEditField.Value),'.mat');
            save(outFileName,'rfData','-v7.3');
        end

        % Button pushed function: StopimagingButton
        function StopimagingButtonPushed(app, event)
            if app.recordingActive
                app.recordingActive = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ResetAppMenu = uimenu(app.FileMenu);
            app.ResetAppMenu.Text = 'Reset App';
            app.ImportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ImportImagingSettingsMenu.Text = 'Import Imaging Settings';
            app.ImportControlSettingsMenu = uimenu(app.FileMenu);
            app.ImportControlSettingsMenu.Text = 'Import Control Settings';
            app.ExportImagingSettingsMenu = uimenu(app.FileMenu);
            app.ExportImagingSettingsMenu.Text = 'Export Imaging Settings';
            app.ExportControlSettingsMenu = uimenu(app.FileMenu);
            app.ExportControlSettingsMenu.Text = 'Export Control Settings';
            app.TestSerialConnectionMenu = uimenu(app.FileMenu);
            app.TestSerialConnectionMenu.Text = 'Test Serial Connection';
            app.ControlMenu = uimenu(app.UIFigure);
            app.ControlMenu.Text = 'Control';
            app.ResetControlParametersMenu = uimenu(app.ControlMenu);
            app.ResetControlParametersMenu.Text = 'Reset Control Parameters';
            app.Menu = uimenu(app.ControlMenu);
            app.ReviewMenu = uimenu(app.UIFigure);
            app.ReviewMenu.Text = 'Review';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.azel_init_axes = uiaxes(app.SetupTab);
            title(app.azel_init_axes, 'Azimuth-Elevation Plane')
            xlabel(app.azel_init_axes, '')
            ylabel(app.azel_init_axes, '')
            app.rael_init_axes = uiaxes(app.SetupTab);
            title(app.rael_init_axes, 'Range-Elevation Plane')
            xlabel(app.rael_init_axes, '')
            ylabel(app.rael_init_axes, '')
            app.raaz_init_axes = uiaxes(app.SetupTab);
            title(app.raaz_init_axes, 'Range-Azimuth Plane')
            xlabel(app.raaz_init_axes, '')
            ylabel(app.raaz_init_axes, '')
            app.surf_init_axes = uiaxes(app.SetupTab);
            title(app.surf_init_axes, '3D ROI')
            xlabel(app.surf_init_axes, '')
            ylabel(app.surf_init_axes, '')
            app.surf_init_axes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.SerialConnectionsPanel = uipanel(app.SetupTab);
            app.SerialConnectionsPanel.Title = 'Serial Connections';
            app.RFControllerLabel = uilabel(app.SerialConnectionsPanel);
            app.RFControllerLabel.Text = 'RF Controller ';
            app.RFControllerDropDown = uidropdown(app.SerialConnectionsPanel);
            app.RFControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @RFControllerDropDownValueChanged, true);
            app.RFOutputDropDownLabel = uilabel(app.SerialConnectionsPanel);
            app.RFOutputDropDownLabel.Text = 'RF Output';
            app.RFOutputDropDown = uidropdown(app.SerialConnectionsPanel);
            app.RFOutputDropDown.ValueChangedFcn = createCallbackFcn(app, @RFOutputDropDownValueChanged, true);
            app.VerifyButton = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton.ButtonPushedFcn = createCallbackFcn(app, @VerifyButtonPushed, true);
            app.VerifyButton.Text = 'Verify';
            app.VerifyButton_2 = uibutton(app.SerialConnectionsPanel, 'push');
            app.VerifyButton_2.Text = 'Verify';
            app.EnableSwitchLabel = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitchLabel.Text = 'Enable';
            app.EnableSwitch = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.EnableSwitch.ValueChangedFcn = createCallbackFcn(app, @EnableSwitchValueChanged, true);
            app.EnableSwitch_2Label = uilabel(app.SerialConnectionsPanel);
            app.EnableSwitch_2Label.Text = 'Enable';
            app.EnableSwitch_2 = uiswitch(app.SerialConnectionsPanel, 'slider');
            app.EnableSwitch_2.ValueChangedFcn = createCallbackFcn(app, @EnableSwitch_2ValueChanged, true);
            app.ReadyLamp_3Label = uilabel(app.SerialConnectionsPanel);
            app.ReadyLamp_3Label.Text = 'Ready';
            app.rfcontrollight = uilamp(app.SerialConnectionsPanel);
            app.ReadyLamp_4Label = uilabel(app.SerialConnectionsPanel);
            app.ReadyLamp_4Label.Text = 'Ready';
            app.rfoutlight = uilamp(app.SerialConnectionsPanel);
            app.resetButton = uibutton(app.SerialConnectionsPanel, 'push');
            app.resetButton.ButtonPushedFcn = createCallbackFcn(app, @resetButtonPushed, true);
            app.resetButton.Text = 'reset';
            app.resetButton_2 = uibutton(app.SerialConnectionsPanel, 'push');
            app.resetButton_2.Text = 'reset';
            app.GeneralControlsPanel = uipanel(app.SetupTab);
            app.GeneralControlsPanel.Title = 'General Controls';
            app.ModeButtonGroup = uibuttongroup(app.GeneralControlsPanel);
            app.ModeButtonGroup.Title = 'Mode';
            app.ControlModeButton = uiradiobutton(app.ModeButtonGroup);
            app.ControlModeButton.Text = 'Control Mode';
            app.ControlModeButton.Value = true;
            app.PrerecordedDataModeButton = uiradiobutton(app.ModeButtonGroup);
            app.PrerecordedDataModeButton.Text = 'Prerecorded Data Mode';
            app.ImagingModeButton = uiradiobutton(app.ModeButtonGroup);
            app.ImagingModeButton.Text = 'Imaging Mode';
            app.ImagingSettingsPanel = uipanel(app.SetupTab);
            app.ImagingSettingsPanel.Title = 'Imaging Settings';
            app.AzimuthAngleEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.AzimuthAngleEditFieldLabel.Text = 'Azimuth Angle';
            app.AzimuthAngleEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.AzimuthAngleEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthAngleEditFieldValueChanged, true);
            app.ElevationAngleEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.ElevationAngleEditFieldLabel.Text = 'Elevation Angle';
            app.ElevationAngleEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.ElevationAngleEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationAngleEditFieldValueChanged, true);
            app.FrameRateEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.FrameRateEditFieldLabel.Text = 'Frame Rate';
            app.FrameRateEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.FrameRateEditField.ValueChangedFcn = createCallbackFcn(app, @FrameRateEditFieldValueChanged, true);
            app.VoxelstepEditFieldLabel = uilabel(app.ImagingSettingsPanel);
            app.VoxelstepEditFieldLabel.Text = 'Voxel step';
            app.VoxelstepEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.VoxelstepEditField.ValueChangedFcn = createCallbackFcn(app, @VoxelstepEditFieldValueChanged, true);
            app.sigmaEditField_2Label = uilabel(app.ImagingSettingsPanel);
            app.sigmaEditField_2Label.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.ImagingSettingsPanel, 'numeric');
            app.FetchInfoButton = uibutton(app.ImagingSettingsPanel, 'push');
            app.FetchInfoButton.ButtonPushedFcn = createCallbackFcn(app, @FetchInfoButtonPushed, true);
            app.FetchInfoButton.Text = 'Fetch Info';
            app.TargetDirectoryPanel = uipanel(app.SetupTab);
            app.TargetDirectoryPanel.Title = 'Target Directory';
            app.SetDirectoryButton = uibutton(app.TargetDirectoryPanel, 'push');
            app.SetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SetDirectoryButtonPushed, true);
            app.SetDirectoryButton.Text = 'Set Directory';
            app.DirectoryLabel = uilabel(app.TargetDirectoryPanel);
            app.DirectoryLabel.Text = 'Directory';
            app.ROISettingsPanel = uipanel(app.SetupTab);
            app.ROISettingsPanel.Title = 'ROI Settings';
            app.TabGroup2 = uitabgroup(app.ROISettingsPanel);
            app.SingleEllipsoidTab = uitab(app.TabGroup2);
            app.SingleEllipsoidTab.Title = 'Single Ellipsoid';
            app.ElevationEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.ElevationEditFieldLabel.Text = 'Elevation';
            app.ElevationEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.ElevationEditField.ValueChangedFcn = createCallbackFcn(app, @ElevationEditFieldValueChanged, true);
            app.AzimuthEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.AzimuthEditField.ValueChangedFcn = createCallbackFcn(app, @AzimuthEditFieldValueChanged, true);
            app.RangeEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.RangeEditFieldLabel.Text = 'Range';
            app.RangeEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.RangeEditField.ValueChangedFcn = createCallbackFcn(app, @RangeEditFieldValueChanged, true);
            app.Rz_outEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.Rz_outEditFieldLabel.Text = 'Rz_out';
            app.Rz_outEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.Rz_outEditField.ValueChangedFcn = createCallbackFcn(app, @Rz_outEditFieldValueChanged, true);
            app.Ry_outEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.Ry_outEditFieldLabel.Text = 'Ry_out';
            app.Ry_outEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.Ry_outEditField.ValueChangedFcn = createCallbackFcn(app, @Ry_outEditFieldValueChanged, true);
            app.Rx_outEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.Rx_outEditFieldLabel.Text = 'Rx_out';
            app.Rx_outEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.Rx_outEditField.ValueChangedFcn = createCallbackFcn(app, @Rx_outEditFieldValueChanged, true);
            app.ROICenterLabel = uilabel(app.SingleEllipsoidTab);
            app.ROICenterLabel.Text = 'ROI Center';
            app.OuterRadiiLabel = uilabel(app.SingleEllipsoidTab);
            app.OuterRadiiLabel.Text = 'Outer Radii';
            app.BetaEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.BetaEditField.ValueChangedFcn = createCallbackFcn(app, @BetaEditFieldValueChanged, true);
            app.GammaEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @GammaEditFieldValueChanged, true);
            app.AlphaEditFieldLabel = uilabel(app.SingleEllipsoidTab);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.AngleLabel = uilabel(app.SingleEllipsoidTab);
            app.AngleLabel.Text = 'Angle ';
            app.ROIModeDropDownLabel = uilabel(app.SingleEllipsoidTab);
            app.ROIModeDropDownLabel.Text = 'ROI Mode';
            app.ROIModeDropDown = uidropdown(app.SingleEllipsoidTab);
            app.ROIModeDropDown.Items = {'Single Ellipsoid', 'Concentric Ellipsoids', 'Ellipsoid with regions', 'Concentric Ellipsoid + regions'};
            app.ROIModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ROIModeDropDownValueChanged, true);
            app.ROIModeDropDown.Value = 'Single Ellipsoid';
            app.RangeMaxLabel = uilabel(app.SingleEllipsoidTab);
            app.RangeMaxLabel.Text = 'RangeMax';
            app.rangeMaxEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.rangeMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @rangeMaxEditField_cutoffValueChanged, true);
            app.rangeMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel = uilabel(app.SingleEllipsoidTab);
            app.RangeMinLabel.Text = 'RangeMin';
            app.rangeMinEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.rangeMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @rangeMinEditField_cutoffValueChanged, true);
            app.rangeMinEditField_cutoff.Value = -1000;
            app.IBSnormboundsLabel = uilabel(app.SingleEllipsoidTab);
            app.IBSnormboundsLabel.Text = 'IBS norm bounds';
            app.SetIBSgeoButton = uibutton(app.SingleEllipsoidTab, 'push');
            app.SetIBSgeoButton.ButtonPushedFcn = createCallbackFcn(app, @SetIBSgeoButtonPushed, true);
            app.SetIBSgeoButton.Text = 'Set IBS geo';
            app.RangeMaxLabel_2 = uilabel(app.SingleEllipsoidTab);
            app.RangeMaxLabel_2.Text = 'ElMax';
            app.elMaxEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.elMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @elMaxEditField_cutoffValueChanged, true);
            app.elMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel_2 = uilabel(app.SingleEllipsoidTab);
            app.RangeMinLabel_2.Text = 'ElMin';
            app.elMinEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.elMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @elMinEditField_cutoffValueChanged, true);
            app.elMinEditField_cutoff.Value = -1000;
            app.RangeMaxLabel_3 = uilabel(app.SingleEllipsoidTab);
            app.RangeMaxLabel_3.Text = 'AzMax';
            app.azMaxEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.azMaxEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @azMaxEditField_cutoffValueChanged, true);
            app.azMaxEditField_cutoff.Value = 1000;
            app.RangeMinLabel_3 = uilabel(app.SingleEllipsoidTab);
            app.RangeMinLabel_3.Text = 'AzMin';
            app.azMinEditField_cutoff = uieditfield(app.SingleEllipsoidTab, 'numeric');
            app.azMinEditField_cutoff.ValueChangedFcn = createCallbackFcn(app, @azMinEditField_cutoffValueChanged, true);
            app.azMinEditField_cutoff.Value = -1000;
            app.MultiEllipsoidTab = uitab(app.TabGroup2);
            app.MultiEllipsoidTab.Title = 'Multi-Ellipsoid';
            app.Rx_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Rx_inEditFieldLabel.Text = 'Rx_in';
            app.Rx_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Rx_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rx_inEditFieldValueChanged, true);
            app.Ry_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Ry_inEditFieldLabel.Text = 'Ry_in';
            app.Ry_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Ry_inEditField.ValueChangedFcn = createCallbackFcn(app, @Ry_inEditFieldValueChanged, true);
            app.Rz_inEditFieldLabel = uilabel(app.MultiEllipsoidTab);
            app.Rz_inEditFieldLabel.Text = 'Rz_in';
            app.Rz_inEditField = uieditfield(app.MultiEllipsoidTab, 'numeric');
            app.Rz_inEditField.ValueChangedFcn = createCallbackFcn(app, @Rz_inEditFieldValueChanged, true);
            app.InnerRadiiLabel = uilabel(app.MultiEllipsoidTab);
            app.InnerRadiiLabel.Text = 'Inner Radii';
            app.ShrinkVolumetoROIButton = uibutton(app.MultiEllipsoidTab, 'push');
            app.ShrinkVolumetoROIButton.ButtonPushedFcn = createCallbackFcn(app, @ShrinkVolumetoROIButtonPushed, true);
            app.ShrinkVolumetoROIButton.Text = 'Shrink Volume to ROI';
            app.RangeSliderLabel = uilabel(app.SetupTab);
            app.RangeSliderLabel.Text = 'Range';
            app.RangeSlider = uislider(app.SetupTab);
            app.RangeSlider.ValueChangedFcn = createCallbackFcn(app, @RangeSliderValueChanged, true);
            app.ElevationLabel_2 = uilabel(app.SetupTab);
            app.ElevationLabel_2.Text = 'Elevation';
            app.ElevationSlider = uislider(app.SetupTab);
            app.ElevationSlider.ValueChangedFcn = createCallbackFcn(app, @ElevationSliderValueChanged, true);
            app.AzimuthLabel_2 = uilabel(app.SetupTab);
            app.AzimuthLabel_2.Text = 'Azimuth';
            app.AzimuthSlider = uislider(app.SetupTab);
            app.AzimuthSlider.ValueChangedFcn = createCallbackFcn(app, @AzimuthSliderValueChanged, true);
            app.AcquireButton_2 = uibutton(app.SetupTab, 'push');
            app.AcquireButton_2.ButtonPushedFcn = createCallbackFcn(app, @AcquireButton_2Pushed, true);
            app.AcquireButton_2.Text = 'Acquire';
            app.AcquireInitVolumeLabel = uilabel(app.SetupTab);
            app.AcquireInitVolumeLabel.Text = 'Acquire Init Volume';
            app.EtcPanel = uipanel(app.SetupTab);
            app.EtcPanel.Title = 'Etc';
            app.UpdateAllButton = uibutton(app.EtcPanel, 'push');
            app.UpdateAllButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateAllButtonPushed, true);
            app.UpdateAllButton.Text = 'Update All';
            app.ResetButton = uibutton(app.EtcPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.ExportSettingsButton = uibutton(app.EtcPanel, 'push');
            app.ExportSettingsButton.Text = 'Export Settings';
            app.ImportSettingsButton = uibutton(app.EtcPanel, 'push');
            app.ImportSettingsButton.Text = 'Import Settings';
            app.DynamicRangeSliderLabel = uilabel(app.SetupTab);
            app.DynamicRangeSliderLabel.Text = 'Dynamic Range';
            app.DynamicRangeSlider = uislider(app.SetupTab);
            app.DynamicRangeSlider.Limits = [1 100];
            app.DynamicRangeSlider.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeSliderValueChanged, true);
            app.DynamicRangeSlider.Value = 60;
            app.DecorrNormalizationButtonGroup = uibuttongroup(app.SetupTab);
            app.DecorrNormalizationButtonGroup.Title = 'Decorr Normalization';
            app.BothButton_2 = uiradiobutton(app.DecorrNormalizationButtonGroup);
            app.BothButton_2.Text = 'Both';
            app.BothButton_2.Value = true;
            app.LocalButton = uiradiobutton(app.DecorrNormalizationButtonGroup);
            app.LocalButton.Text = 'Local';
            app.GlobalButton = uiradiobutton(app.DecorrNormalizationButtonGroup);
            app.GlobalButton.Text = 'Global';
            app.TrialEditFieldLabel = uilabel(app.SetupTab);
            app.TrialEditFieldLabel.Text = 'Trial #';
            app.TrialEditField = uieditfield(app.SetupTab, 'numeric');
            app.ImagingTab = uitab(app.TabGroup);
            app.ImagingTab.Title = 'Imaging';
            app.decorr_imaging_axes = uiaxes(app.ImagingTab);
            title(app.decorr_imaging_axes, 'Decorrelation Over TIme')
            xlabel(app.decorr_imaging_axes, 'X')
            ylabel(app.decorr_imaging_axes, 'Y')
            app.azel_imaging_axes = uiaxes(app.ImagingTab);
            title(app.azel_imaging_axes, 'Azimuth-Elevation Plane')
            xlabel(app.azel_imaging_axes, '')
            ylabel(app.azel_imaging_axes, '')
            app.rael_imaging_axes = uiaxes(app.ImagingTab);
            title(app.rael_imaging_axes, 'Range-Elevation Plane')
            xlabel(app.rael_imaging_axes, '')
            ylabel(app.rael_imaging_axes, '')
            app.raaz_imaging_axes = uiaxes(app.ImagingTab);
            title(app.raaz_imaging_axes, 'Range-Azimuth Plane')
            xlabel(app.raaz_imaging_axes, '')
            ylabel(app.raaz_imaging_axes, '')
            app.rfout_axes = uiaxes(app.ImagingTab);
            title(app.rfout_axes, 'RF Generator Data Output')
            xlabel(app.rfout_axes, 'X')
            ylabel(app.rfout_axes, 'Y')
            app.ImagingcontrolsPanel = uipanel(app.ImagingTab);
            app.ImagingcontrolsPanel.Title = 'Imaging controls';
            app.TrialtypeButtonGroup = uibuttongroup(app.ImagingcontrolsPanel);
            app.TrialtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TrialtypeButtonGroupSelectionChanged, true);
            app.TrialtypeButtonGroup.Title = 'Trial type';
            app.ManualImagingModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.ManualImagingModeButton.Text = 'Manual Imaging Mode';
            app.ManualImagingModeButton.Value = true;
            app.AutomaticImagingModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.AutomaticImagingModeButton.Text = 'Automatic Imaging Mode';
            app.ManualControlModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.ManualControlModeButton.Text = 'Manual Control Mode';
            app.AutomaticControlModeButton = uiradiobutton(app.TrialtypeButtonGroup);
            app.AutomaticControlModeButton.Text = 'Automatic Control Mode';
            app.ControlThreshEditFieldLabel = uilabel(app.ImagingcontrolsPanel);
            app.ControlThreshEditFieldLabel.Text = 'Control Thresh';
            app.ControlThreshEditField = uieditfield(app.ImagingcontrolsPanel, 'numeric');
            app.ControlThreshEditField.ValueChangedFcn = createCallbackFcn(app, @ControlThreshEditFieldValueChanged, true);
            app.MotioncorrectCheckBox = uicheckbox(app.ImagingcontrolsPanel);
            app.MotioncorrectCheckBox.Text = 'Motion correct';
            app.NumshamEditFieldLabel = uilabel(app.ImagingcontrolsPanel);
            app.NumshamEditFieldLabel.Text = 'Num sham';
            app.NumshamEditField = uieditfield(app.ImagingcontrolsPanel, 'numeric');
            app.RangeSlider_2Label = uilabel(app.ImagingTab);
            app.RangeSlider_2Label.Text = 'Range';
            app.RangeSlider_imaging = uislider(app.ImagingTab);
            app.RangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_imagingValueChanged, true);
            app.ElevationLabel_3 = uilabel(app.ImagingTab);
            app.ElevationLabel_3.Text = 'Elevation';
            app.ElevationSlider_imaging = uislider(app.ImagingTab);
            app.ElevationSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @ElevationSlider_imagingValueChanged, true);
            app.AzimuthLabel_3 = uilabel(app.ImagingTab);
            app.AzimuthLabel_3.Text = 'Azimuth';
            app.AzimuthSlider_imaging = uislider(app.ImagingTab);
            app.AzimuthSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @AzimuthSlider_imagingValueChanged, true);
            app.AzimuthSlider_imaging.ValueChangingFcn = createCallbackFcn(app, @AzimuthSlider_imagingValueChanging, true);
            app.BmodeDynamicRangeLabel = uilabel(app.ImagingTab);
            app.BmodeDynamicRangeLabel.Text = 'Bmode Dyn. Range';
            app.DynamicRangeSlider_imaging = uislider(app.ImagingTab);
            app.DynamicRangeSlider_imaging.Limits = [1 200];
            app.DynamicRangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @DynamicRangeSlider_imagingValueChanged, true);
            app.DynamicRangeSlider_imaging.Value = 60;
            app.ManualPanel = uipanel(app.ImagingTab);
            app.ManualPanel.Title = 'Manual';
            app.RecordVolumeButton = uibutton(app.ManualPanel, 'push');
            app.RecordVolumeButton.ButtonPushedFcn = createCallbackFcn(app, @RecordVolumeButtonPushed, true);
            app.RecordVolumeButton.Text = 'Record Volume';
            app.ReadyLampLabel = uilabel(app.ManualPanel);
            app.ReadyLampLabel.Text = 'Ready?';
            app.ReadyLamp = uilamp(app.ManualPanel);
            app.CurrentVolumeEditFieldLabel = uilabel(app.ManualPanel);
            app.CurrentVolumeEditFieldLabel.Text = 'Current Volume';
            app.CurrentVolumeEditField = uieditfield(app.ManualPanel, 'text');
            app.CompletionTimeEditFieldLabel = uilabel(app.ManualPanel);
            app.CompletionTimeEditFieldLabel.Text = 'Completion Time';
            app.CompletionTimeManualEditField = uieditfield(app.ManualPanel, 'text');
            app.CompletionTimeManualEditField.Editable = 'off';
            app.AutomaticPanel = uipanel(app.ImagingTab);
            app.AutomaticPanel.Title = 'Automatic';
            app.ReadyLamp_2Label = uilabel(app.AutomaticPanel);
            app.ReadyLamp_2Label.Text = 'Ready?';
            app.ReadyLamp_2 = uilamp(app.AutomaticPanel);
            app.StartRecordingButton = uibutton(app.AutomaticPanel, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @StartRecordingButtonPushed, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.PauseButton = uibutton(app.AutomaticPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.StopButton = uibutton(app.AutomaticPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.ActualvolumesEditFieldLabel = uilabel(app.AutomaticPanel);
            app.ActualvolumesEditFieldLabel.Text = 'Actual volumes';
            app.ActualvolumesEditField = uieditfield(app.AutomaticPanel, 'text');
            app.ActualvolumesEditField.Editable = 'off';
            app.CompletionTimeEditFieldLabel_2 = uilabel(app.AutomaticPanel);
            app.CompletionTimeEditFieldLabel_2.Text = 'Completion Time';
            app.CompletionTimeAutoEditField = uieditfield(app.AutomaticPanel, 'text');
            app.CompletionTimeAutoEditField.Editable = 'off';
            app.EndTrialButton = uibutton(app.AutomaticPanel, 'push');
            app.EndTrialButton.ButtonPushedFcn = createCallbackFcn(app, @EndTrialButtonPushed, true);
            app.EndTrialButton.Text = 'End Trial';
            app.ShamvolumesEditFieldLabel = uilabel(app.AutomaticPanel);
            app.ShamvolumesEditFieldLabel.Text = 'Sham volumes';
            app.ShamvolumesEditField = uieditfield(app.AutomaticPanel, 'numeric');
            app.ShamvolumesEditField.Editable = 'off';
            app.SaveRFButton = uibutton(app.AutomaticPanel, 'push');
            app.SaveRFButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRFButtonPushed, true);
            app.SaveRFButton.Text = 'Save RF';
            app.CurrentDecorrelationEditFieldLabel = uilabel(app.ImagingTab);
            app.CurrentDecorrelationEditFieldLabel.Text = {'Current'; 'Decorrelation'; ''};
            app.CurrentDecorrelationEditField = uieditfield(app.ImagingTab, 'numeric');
            app.CurrentDecorrelationEditField.Editable = 'off';
            app.ElapsedTimeEditFieldLabel = uilabel(app.ImagingTab);
            app.ElapsedTimeEditFieldLabel.Text = 'Elapsed Time';
            app.ElapsedTimeEditField = uieditfield(app.ImagingTab, 'numeric');
            app.ElapsedTimeEditField.Editable = 'off';
            app.logArtifDecorrEditFieldLabel = uilabel(app.ImagingTab);
            app.logArtifDecorrEditFieldLabel.Text = 'log Artif. Decorr:';
            app.logArtifDecorrEditField = uieditfield(app.ImagingTab, 'numeric');
            app.logArtifDecorrEditField.Editable = 'off';
            app.DynamicRangeSlider_2Label_2 = uilabel(app.ImagingTab);
            app.DynamicRangeSlider_2Label_2.Text = 'Decorr Dyn. Range';
            app.DecorrDynamicRangeSlider_imaging = uislider(app.ImagingTab);
            app.DecorrDynamicRangeSlider_imaging.Limits = [1 100];
            app.DecorrDynamicRangeSlider_imaging.ValueChangedFcn = createCallbackFcn(app, @DecorrDynamicRangeSlider_imagingValueChanged, true);
            app.DecorrDynamicRangeSlider_imaging.Value = 60;
            app.MindecdisplayEditFieldLabel = uilabel(app.ImagingTab);
            app.MindecdisplayEditFieldLabel.Text = 'Min dec. display';
            app.MindecdisplayEditField = uieditfield(app.ImagingTab, 'numeric');
            app.MindecdisplayEditField.Value = -6;
            app.OverridePanel = uipanel(app.ImagingTab);
            app.OverridePanel.Title = 'Override';
            app.ActivateRFgenButton = uibutton(app.OverridePanel, 'push');
            app.ActivateRFgenButton.Text = 'Activate RF gen.';
            app.StopimagingButton = uibutton(app.OverridePanel, 'push');
            app.StopimagingButton.ButtonPushedFcn = createCallbackFcn(app, @StopimagingButtonPushed, true);
            app.StopimagingButton.Text = 'Stop imaging';
            app.ReviewTab = uitab(app.TabGroup);
            app.ReviewTab.Title = 'Review';
            app.azel_review_axes = uiaxes(app.ReviewTab);
            title(app.azel_review_axes, 'Azimuth-Elevation Plane')
            xlabel(app.azel_review_axes, '')
            ylabel(app.azel_review_axes, '')
            app.raaz_review_axes = uiaxes(app.ReviewTab);
            title(app.raaz_review_axes, 'Range-Elevation Plane')
            xlabel(app.raaz_review_axes, 'X')
            ylabel(app.raaz_review_axes, 'Y')
            app.rael_review_axes = uiaxes(app.ReviewTab);
            title(app.rael_review_axes, 'Range-Azimiuth Plane')
            xlabel(app.rael_review_axes, 'X')
            ylabel(app.rael_review_axes, 'Y')
            app.review_decorr_axes = uiaxes(app.ReviewTab);
            title(app.review_decorr_axes, 'Decorr Over Time')
            xlabel(app.review_decorr_axes, 'X')
            ylabel(app.review_decorr_axes, 'Y')
            app.ReviewControlPanel = uipanel(app.ReviewTab);
            app.ReviewControlPanel.Title = 'Review Control';
            app.LoadExperimentButton = uibutton(app.ReviewControlPanel, 'push');
            app.LoadExperimentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentButtonPushed, true);
            app.LoadExperimentButton.Text = 'Load Experiment';
            app.Label = uilabel(app.ReviewControlPanel);
            app.DirectoryLabel_2 = uilabel(app.ReviewControlPanel);
            app.DirectoryLabel_2.Text = 'Directory:';
            app.LoadExperimentfromfileButton = uibutton(app.ReviewControlPanel, 'push');
            app.LoadExperimentfromfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadExperimentfromfileButtonPushed, true);
            app.LoadExperimentfromfileButton.Text = 'Load Experiment from file';
            app.ExportButton = uibutton(app.ReviewControlPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.SigmaEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.SigmaEditFieldLabel.Text = 'Sigma';
            app.SigmaEditField = uieditfield(app.ReviewControlPanel, 'numeric');
            app.ThreshEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.ThreshEditFieldLabel.Text = 'Thresh';
            app.ThreshEditField = uieditfield(app.ReviewControlPanel, 'numeric');
            app.SigmaEditField_2Label = uilabel(app.ReviewControlPanel);
            app.SigmaEditField_2Label.Text = 'Sigma';
            app.SigmaEditField_2 = uieditfield(app.ReviewControlPanel, 'text');
            app.NumberofVolumesEditFieldLabel = uilabel(app.ReviewControlPanel);
            app.NumberofVolumesEditFieldLabel.Text = 'Number of Volumes';
            app.NumberofVolumesEditField = uieditfield(app.ReviewControlPanel, 'text');
            app.DecorrThreshEditField_2Label = uilabel(app.ReviewControlPanel);
            app.DecorrThreshEditField_2Label.Text = 'Decorr Thresh';
            app.DecorrThreshEditField_2 = uieditfield(app.ReviewControlPanel, 'text');
            app.FrameRateEditField_2Label = uilabel(app.ReviewControlPanel);
            app.FrameRateEditField_2Label.Text = 'Frame Rate';
            app.FrameRateEditField_2 = uieditfield(app.ReviewControlPanel, 'numeric');
            app.Panel2 = uipanel(app.ReviewTab);
            app.Panel2.Title = 'Panel2';
            app.beeginbutton = uibutton(app.Panel2, 'push');
            app.beeginbutton.Text = '<<';
            app.endbutton = uibutton(app.Panel2, 'push');
            app.endbutton.Text = '>>';
            app.back1button = uibutton(app.Panel2, 'push');
            app.back1button.Text = '<';
            app.forward1button = uibutton(app.Panel2, 'push');
            app.forward1button.Text = '>';
            app.SelectDataDropDownLabel = uilabel(app.Panel2);
            app.SelectDataDropDownLabel.Text = 'Select Data';
            app.SelectDataDropDown = uidropdown(app.Panel2);
            app.SelectDataDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataDropDownValueChanged, true);
            app.Panel2_2 = uipanel(app.ReviewTab);
            app.Panel2_2.Title = 'Panel2';
            app.RangeSlider_2Label_2 = uilabel(app.ReviewTab);
            app.RangeSlider_2Label_2.Text = 'Range';
            app.RangeSlider_review = uislider(app.ReviewTab);
            app.RangeSlider_review.ValueChangedFcn = createCallbackFcn(app, @RangeSlider_reviewValueChanged, true);
            app.ElevationLabel = uilabel(app.ReviewTab);
            app.ElevationLabel.Text = 'Elevation';
            app.ElevationSlider_review = uislider(app.ReviewTab);
            app.AzimuthLabel = uilabel(app.ReviewTab);
            app.AzimuthLabel.Text = 'Azimuth';
            app.AzimuthSlider_review = uislider(app.ReviewTab);
            app.AzimuthSlider_review.ValueChangedFcn = createCallbackFcn(app, @AzimuthSlider_reviewValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DecorrApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end